classdef Record_IR_Video_App_v01282021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SaveLocation             matlab.ui.container.Menu
        FileName                 matlab.ui.container.Menu
        OPTIONS                  matlab.ui.container.Menu
        RESET_Camera             matlab.ui.container.Menu
        CheckToolBoxesCn         matlab.ui.container.Menu
        PreviewButton            matlab.ui.control.StateButton
        RECORDButton             matlab.ui.control.StateButton
        StatusTextAreaLabel      matlab.ui.control.Label
        Status                   matlab.ui.control.TextArea
        RecordDurationminsLabel  matlab.ui.control.Label
        DurMins                  matlab.ui.control.NumericEditField
        RecordSaveLocationLabel  matlab.ui.control.Label
        RecLoc                   matlab.ui.control.EditField
        Video                    matlab.ui.control.UIAxes
    end

    %%%%% TO DO
    %%%%% 5. If possible check 6 minuate file creation blocks


    properties (Access = private)
        vid % Description
        vidLog
        statMessage
        fname
        useDIR
        imNUM
        fnameVid
        VidInfo
        FrameGrabInterval
        FramesPerTrigger
        FrameRateRec
        FrameRateAqu
        TotalFrames
        ardUINO
        ardCheck
        startPreview
        NUMhourBlocks
        blockINFO
        
    end
    
    
    methods (Access = private)
        
        function [] = updateSTATMmsg(app , newMSG)
            
            tmpMesg = [newMSG , datestr(datetime('now'))];
            app.statMessage(length(app.statMessage) + 1,1) = {tmpMesg};
            app.Status.Value = app.statMessage;
            
        end
        
        function [] = totalFrameNum(app)
            
            app.TotalFrames = round(app.DurMins.Value)*60*10; % minutes * seconds * subSample
            
        end
        
        
        function updatePREVIEW(app)
            
            updateSTATMmsg(app , 'Preview Started: ')
            while app.startPreview
                
                snapshot = getsnapshot(app.vid);
                
                grayImage = rgb2gray(snapshot);
                invertImage = flipud(grayImage);
                imshow(invertImage,'Parent',app.Video)
                pause(0.001)
                drawnow
                
            end
            
            updateSTATMmsg(app , 'Preview Stopped: ')
            
            app.RECORDButton.Enable = 'on';
            app.PreviewButton.Enable = 'off';
            
            % Tell user to set Save directory
            message = sprintf('Check Recording Duration');
            uialert(app.UIFigure,message,'WARNING - Check recording time',...
                'Icon','warning');
            
        end
        
        function setVideoUP(app)
            
            app.vidLog = VideoWriter([app.useDIR , filesep , app.fnameVid], 'MPEG-4');
            app.vidLog.FrameRate = 10;
            app.vidLog.Quality = 75;
            
            app.vid.DiskLogger = app.vidLog;
            
            app.VidInfo.FrameGrabInterval = get(app.vid,'FrameGrabInterval');
            app.VidInfo.FramesPerTrigger = get(app.vid,'FramesPerTrigger');
            
            vidLOG = get(app.vid.DiskLogger);
            app.VidInfo.FrameRateRec = vidLOG.FrameRate;
            
            vidSrc = getselectedsource(app.vid);
            app.VidInfo.FrameRateAqu = str2double(get(vidSrc,'FrameRate'));
            
        end
        
        function getBlockInfo(app)
            
            minHOURs = floor(app.DurMins.Value/60);
            minMINs = app.DurMins.Value - (minHOURs*60);
            
            if minHOURs == 0
                app.blockINFO.totalBINS = 1;
            elseif minMINs ~= 0
                app.blockINFO.totalBINS = minHOURs + 1;
            else
                app.blockINFO.totalBINS = minHOURs;
            end
            
            hourFramPerT = repmat(36000, 1, minHOURs);
            minsFramPerT = round((minMINs)*60*10);
            
            if minHOURs == 0
                app.blockINFO.fraPerT = minsFramPerT;
            elseif minMINs ~= 0
                app.blockINFO.fraPerT = [hourFramPerT , minsFramPerT];
            else
                app.blockINFO.fraPerT = hourFramPerT;
            end
            
        end
        
        function updateRECording(app)
            
            % Samples per hour 36000
            % app.vid.
            % Get block info
            % 1. total number of blocks
            % 2. Equal to full hours and extra minutes
            % 3. Output vector with Frames per Trigger
            % Check if recording is taking place
            getBlockInfo(app)
            while app.RECORDButton.Value &&...
                    app.blockINFO.curB <= app.blockINFO.totalBINS
            
            %             getBlockInfo(app)
            updateSTATMmsg(app , 'Recording Started: ')
            app.RecLoc.Enable = 'off';
            
            % Loop through number of hour blocks
            tmpBlock = app.blockINFO.curB;
            app.fnameVid = [app.fname, '_', num2str(app.blockINFO.curB),'.mp4'];
            
            setVideoUP(app)
            
            app.vid.FramesPerTrigger = app.blockINFO.fraPerT(app.blockINFO.curB);
            start(app.vid);
            tic;
            % Set background
            imshow(zeros(480,640,'uint8'),'Parent',app.Video)
            text(app.Video, 100, 300,['Recording hour ',num2str(tmpBlock)],...
                'Color','red',...
                'FontSize',54,'FontWeight','bold')
            
            %%% TTL CODE - START %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.ardCheck
                for ttli = 1:tmpBlock
                    writeDigitalPin(app.ardUINO, 'D2', 0);
                    writeDigitalPin(app.ardUINO, 'D2', 1);
                    pause(0.005)
                    writeDigitalPin(app.ardUINO, 'D2', 0);
                end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            wait(app.vid,Inf);
            endRecTime = toc;
            
            %%% TTL CODE - END   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.ardCheck
                for ttli = 1:tmpBlock
                    writeDigitalPin(app.ardUINO, 'D2', 0);
                    writeDigitalPin(app.ardUINO, 'D2', 1);
                    pause(0.005)
                    writeDigitalPin(app.ardUINO, 'D2', 0);
                end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Save out text file with recording start time
            startRecTime = datestr(app.vid.InitialTriggerTime);
            saveTxtStTime = [app.useDIR, filesep, app.fname, '_', num2str(app.blockINFO.curB) , '_startTime.txt'];

            durTottime = datetime(startRecTime) + seconds(endRecTime);
            endRecTimeSave = datestr(durTottime);
            saveTxtDurTime = [app.useDIR, filesep, app.fname, '_', num2str(app.blockINFO.curB) , '_endTime.txt'];
            
            fileID = fopen(saveTxtStTime,'w');
            fprintf(fileID,startRecTime);
            fclose(fileID);
            
            fileID2 = fopen(saveTxtDurTime,'w');
            fprintf(fileID2,endRecTimeSave);
            fclose(fileID2);
            
            stop(app.vid);
            
            app.blockINFO.curB = app.blockINFO.curB + 1;
            
            end
            
            % Check if stopped early
            stop(app.vid);
            lastBl = app.blockINFO.curB;
            % Finished on time
            if lastBl > app.blockINFO.totalBINS
                lastBl = lastBl - 1;
                
                updateSTATMmsg(app , ['Recording Stopped on block ', num2str(lastBl)])
                
                % Update Background
                % Set background
                imshow(zeros(480,640,'uint8'),'Parent',app.Video)
                text(app.Video, 50, 200,'Select Preview to restart','Color','green',...
                    'FontSize',44,'FontWeight','bold')
                
                app.RecLoc.Enable = 'off';
                
            % Exited early
            else
                %%% TTL CODE - END   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.ardCheck
                    for ttli = 1:lastBl
                        writeDigitalPin(app.ardUINO, 'D2', 0);
                        writeDigitalPin(app.ardUINO, 'D2', 1);
                        pause(0.005)
                        writeDigitalPin(app.ardUINO, 'D2', 0);
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % Save out text file with recording start time
                startRecTime = datestr(app.vid.InitialTriggerTime);
                
                saveTxtLoc = [app.useDIR, filesep, app.fname,...
                    '_', num2str(app.blockINFO.curB) , '_startTime.txt'];
                
                endRecTime = toc;
                durTottime = datetime(startRecTime) + seconds(endRecTime);
                endRecTimeSave = datestr(durTottime);
                saveTxtDurTime = [app.useDIR, filesep, app.fname,...
                    '_', num2str(app.blockINFO.curB) , '_endTime.txt'];
                
                fileID = fopen(saveTxtLoc,'w');
                fprintf(fileID,startRecTime);
                fclose(fileID);
                
                fileID2 = fopen(saveTxtDurTime,'w');
                fprintf(fileID2,endRecTimeSave);
                fclose(fileID2);
                
                clear app.vid
                updateSTATMmsg(app , 'Recording Stopped: ')
                
                % Update Background
                % Set background
                imshow(zeros(480,640,'uint8'),'Parent',app.Video)
                text(app.Video, 50, 200,'Select Preview to restart','Color','green',...
                    'FontSize',44,'FontWeight','bold')
            end
            app.RECORDButton.Enable = 'off';
%             app.PreviewButton.Enable = 'on';
            
            UIFigureCloseRequest(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function loadVideo(app)
            % Obtain list of connected cameras
            info = imaqhwinfo('winvideo');
            listOfCams = {info.DeviceInfo.DeviceName};
            
            % Error check for Camera
            if ~ismember('HD USB Camera',listOfCams)
                ME = MException('MyComponent:noSuchVariable', ...
                    'Variable %s not found','HD USB Camera');
                UIFigureCloseRequest(app)
                throw(ME)
            end
            
            app.imNUM = find(ismember(listOfCams,'HD USB Camera'));
            
            adaptorName = 'winvideo';
            deviceID = app.imNUM;
            vidFormat = 'YUY2_640x480';
            
            app.vid = videoinput(adaptorName, deviceID, vidFormat);
            
            app.vid.LoggingMode = 'disk';
            app.vid.TriggerRepeat = 0;
            
            % totalFrameNum(app) = should default to 600
            totalFrameNum(app);
            app.vid.FramesPerTrigger = app.TotalFrames;% 600*3/30 = 60; || frames*3/frameRate = number of seconds recorded
            app.vid.FrameGrabInterval = 3;
            
            % Camera ready
            app.statMessage = {['Camera Initiated: ', datestr(datetime('now'))]};
            app.Status.Value = app.statMessage;
            % Default record time
            updateSTATMmsg(app ,['Default recording duration: ', num2str(app.TotalFrames), ' seconds '])
            
            % Setup Arduino
            % Error check for ARDUINO
            try
                app.ardUINO = arduino();
                app.ardCheck = 1;
            catch
                app.ardCheck = 0;
                ME = MException('MyComponent:noSuchVariable', ...
                    'Variable %s not found','ARDUINO');
                UIFigureCloseRequest(app)
                throw(ME)
            end
            
            % Get number of hour blocks
            app.NUMhourBlocks = round(app.DurMins.Value/60);
            
            % Set active items
            app.RECORDButton.Enable = 'off';
            app.PreviewButton.Enable = 'off';
            
            % Tell user to set Save directory
            message = sprintf('Choose Save Directory in File Options');
            uialert(app.UIFigure,message,'Choose Save Location',...
                'Icon','info');
            
            app.blockINFO.curB = 1;
            
        end

        % Value changed function: PreviewButton
        function PreviewButtonValueChanged(app, event)
            value = app.PreviewButton.Value;
            
            if value
                app.startPreview = 1;
            else
                app.startPreview = 0;
            end
            updatePREVIEW(app)
            
        end

        % Value changed function: RECORDButton
        function RECORDButtonValueChanged(app, event)
            
            value = app.RECORDButton.Value;
            % Samples per hour 36000
            % app.vid.
            % Get block info
            % 1. total number of blocks
            % 2. Equal to full hours and extra minutes
            % 3. Output vector with Frames per Trigger
            
            if value
                updateRECording(app)


            else % Stop the video early
                updateRECording(app)
                
                %                 stop(app.vid);
                %                 clear app.vid
                %                 close(app.vidLog);
                
            end
            
        end

        % Menu selected function: SaveLocation
        function SaveLocationMenuSelected(app, event)
            app.useDIR = uigetdir();
            
            checkC = 1;
            while checkC
                if app.useDIR ~= 0
                    checkC = 0;
                else
                    app.useDIR = uigetdir();
                end
            end
            
            app.RecLoc.Value = app.useDIR;
            app.RecLoc.FontColor = [0 0 0];
            
            app.FileName.Enable = 'on';
            
            % Tell user to set Save directory
            message = sprintf('Enter File Name in File Options');
            uialert(app.UIFigure,message,'Enter File Name',...
                'Icon','info');
            
        end

        % Menu selected function: FileName
        function FileNameMenuSelected(app, event)
            
            prompt = {'Enter file name'};
            dlgtitle = {'PatientID', 'Institution','Night#'};
            definput = {'1','UNMC','1'};
            dims = [1 50; 1 50; 1 50];
            outP = inputdlg(dlgtitle,prompt{1},dims,definput);
            
            app.fname = [outP{1},'_',outP{2},'_',outP{3}];
            app.fnameVid = [app.fname,'.mp4'];
            
            app.vidLog = VideoWriter([app.useDIR , filesep , app.fnameVid], 'MPEG-4');
            app.vidLog.FrameRate = 10;
            app.vidLog.Quality = 75;
            
            app.vid.DiskLogger = app.vidLog;
            
            app.VidInfo.FrameGrabInterval = get(app.vid,'FrameGrabInterval');
            app.VidInfo.FramesPerTrigger = get(app.vid,'FramesPerTrigger');
            
            vidLOG = get(app.vid.DiskLogger);
            app.VidInfo.FrameRateRec = vidLOG.FrameRate;
            
            vidSrc = getselectedsource(app.vid);
            app.VidInfo.FrameRateAqu = str2double(get(vidSrc,'FrameRate'));
            
            % Update message
            updateSTATMmsg(app , ['FrameGrabInterval: ', num2str(app.VidInfo.FrameGrabInterval), ' '])
            updateSTATMmsg(app , ['FramesPerTrigger: ', num2str(app.VidInfo.FramesPerTrigger), ' '])
            updateSTATMmsg(app , ['FrameRateRecord: ', num2str(app.VidInfo.FrameRateRec), ' '])
            updateSTATMmsg(app , ['FrameRateAcquire: ', num2str(app.VidInfo.FrameRateAqu), ' '])
            
            app.RecLoc.Value = [app.useDIR , filesep , app.fnameVid];
            
            app.PreviewButton.Enable = 'on';
            app.OPTIONS.Enable = 'on';
            
        end

        % Value changed function: DurMins
        function DurMinsValueChanged(app, event)
            
            % Update totalFrames based on new input
            totalFrameNum(app)
            
            % Get new TotalFrames value
            app.vid.FramesPerTrigger = app.TotalFrames;
            
            % Get duration in minutes to print
            durMINS = app.DurMins.Value*60;
            
            % Calculate number of blocks - will need to convert into
            % function to calculate hours and minutes
            app.NUMhourBlocks = round(app.DurMins.Value/60);
            
            updateSTATMmsg(app ,['Recording duration: ', num2str(durMINS) ,' seconds: '])
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: RESET_Camera
        function RESET_CameraMenuSelected(app, event)
            adaptorName = 'winvideo';
            deviceID = app.imNUM;
            vidFormat = 'YUY2_640x480';
            
            app.vid = videoinput(adaptorName, deviceID, vidFormat);
            
            app.vid.LoggingMode = 'disk';
            app.vid.TriggerRepeat = 0;
            app.vid.FramesPerTrigger = app.TotalFrames; % 600*3/30 = 60; || frames*3/frameRate = number of seconds recorded
            app.vid.FrameGrabInterval = 3;
            
            % Camera ready
            app.statMessage = {['Camera Initiated: ', datestr(datetime('now'))]};
            app.Status.Value = app.statMessage;
            % Default record time
            updateSTATMmsg(app ,'Default recording duration: 60 seconds ')
            
            % Set active items
            app.RECORDButton.Enable = 'off';
            
            app.vidLog = VideoWriter([app.useDIR , filesep , app.fname], 'MPEG-4');
            app.vidLog.FrameRate = 10;
            app.vidLog.Quality = 75;
            
            app.vid.DiskLogger = app.vidLog;
            app.RecLoc.Value = [app.useDIR , filesep , app.fname];
            app.PreviewButton.Enable = 'on';
            app.RECORDButton.Value = 0;
            
        end

        % Menu selected function: CheckToolBoxesCn
        function CheckToolBoxesCnMenuSelected(app, event)
            
            addons = matlab.addons.installedAddons;
            for ci = 1:4
                switch ci
                    case 1
                        ck = license('checkout', 'Image_Acquisition_Toolbox');
                        nameF = 'Image Acquisition Toolbox';
                    case 2
                        ck = license('checkout', 'Video_and_Image_Blockset');
                        nameF = 'Computer Vision Toolbox';
                    case 3
                        ck = any(contains(addons.Identifier,'OSVIDEO'));
                        nameF = 'Image Acquisition Hardware AddOn';
                    case 4
                        ck = any(contains(addons.Identifier,'ML_ARDUINO'));
                        nameF = 'Arduino AddOn';
                end
                
                if ck
                    updateSTATMmsg(app , [nameF, '  Installed: '])
                else
                    updateSTATMmsg(app , [nameF, '  NOT Installed: '])
                end
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveLocation = uimenu(app.UIFigure);
            app.SaveLocation.MenuSelectedFcn = createCallbackFcn(app, @SaveLocationMenuSelected, true);
            app.SaveLocation.Text = 'SaveDirectory';
            app.FileName = uimenu(app.UIFigure);
            app.FileName.MenuSelectedFcn = createCallbackFcn(app, @FileNameMenuSelected, true);
            app.FileName.Enable = 'off';
            app.FileName.Text = 'FileName';
            app.OPTIONS = uimenu(app.UIFigure);
            app.OPTIONS.Enable = 'off';
            app.OPTIONS.Text = 'Options';
            app.RESET_Camera = uimenu(app.OPTIONS);
            app.RESET_Camera.MenuSelectedFcn = createCallbackFcn(app, @RESET_CameraMenuSelected, true);
            app.RESET_Camera.Enable = 'off';
            app.RESET_Camera.Text = 'ResetCamera';
            app.CheckToolBoxesCn = uimenu(app.OPTIONS);
            app.CheckToolBoxesCn.MenuSelectedFcn = createCallbackFcn(app, @CheckToolBoxesCnMenuSelected, true);
            app.CheckToolBoxesCn.Text = 'CheckToolboxes';
            app.PreviewButton = uibutton(app.UIFigure, 'state');
            app.PreviewButton.ValueChangedFcn = createCallbackFcn(app, @PreviewButtonValueChanged, true);
            app.PreviewButton.Text = 'Preview';
            app.RECORDButton = uibutton(app.UIFigure, 'state');
            app.RECORDButton.ValueChangedFcn = createCallbackFcn(app, @RECORDButtonValueChanged, true);
            app.RECORDButton.Text = 'RECORD';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.Status = uitextarea(app.UIFigure);
            app.RecordDurationminsLabel = uilabel(app.UIFigure);
            app.RecordDurationminsLabel.Text = 'Record Duration (mins)';
            app.DurMins = uieditfield(app.UIFigure, 'numeric');
            app.DurMins.ValueChangedFcn = createCallbackFcn(app, @DurMinsValueChanged, true);
            app.DurMins.Editable = 'off';
            app.DurMins.Value = 660;
            app.RecordSaveLocationLabel = uilabel(app.UIFigure);
            app.RecordSaveLocationLabel.Text = 'Record Save Location';
            app.RecLoc = uieditfield(app.UIFigure, 'text');
            app.RecLoc.Editable = 'off';
            app.RecLoc.Value = 'File Save location';
            app.Video = uiaxes(app.UIFigure);
            app.Video.XTick = [];
            app.Video.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Record_IR_Video_App_v01282021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @loadVideo)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end