classdef Record_IR_Video_and_Stim_v03042021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SaveLocation             matlab.ui.container.Menu
        FileName                 matlab.ui.container.Menu
        OPTIONS                  matlab.ui.container.Menu
        CheckToolBoxesCn         matlab.ui.container.Menu
        CLOSEMenu                matlab.ui.container.Menu
        PreviewButton            matlab.ui.control.StateButton
        StatusTextAreaLabel      matlab.ui.control.Label
        Status                   matlab.ui.control.TextArea
        RecordSaveLocationLabel  matlab.ui.control.Label
        RecLoc                   matlab.ui.control.EditField
        StartButton              matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        StimulationButton        matlab.ui.control.Button
        Video                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vid                  % class for streaming data from the camera
        vidLog               % class for logging video to disk
        useDIR               % directory for saving
        ard                  % Arduino class
        
        mainTimerDuration    % The duration between each trigger, governs time between stimulations and amount of video pulled at once
        maxRecordingDuration % Max number of seconds to record
        logFile              % File ID to writeout a copy of the status log
        mainTimer            % Main timer to control when to stimulate and get the next piece of video
        keepRecording        % Flag to continue data acquisition
        stimParam            % Structure to hold stimulation parameters
        AO_connected         % If Alpha Omega system is connected
        firstTimeSavingLog   % Flag for saving the log
        videoDurationPerTrigger % Amount of video logged within each mainTimerDuration
        fname                % base filename
    end
    
    
    methods (Access = private)
        
        % Function to send messages in the dialog interface %%%%%%%%%%%%%%%
        function [] = addToStatus(app , newMSG)
            tmpMesg = [ datestr(now, 'HH:MM:SS.FFF') '  ' newMSG ];
            app.Status.Value = [ app.Status.Value; {tmpMesg} ];
            
            if( app.logFile > 0)
                if( app.firstTimeSavingLog )
                    for i=1:length(app.Status.Value)
                        fprintf( app.logFile, '%s\n', app.Status.Value{i});
                    end
                    app.firstTimeSavingLog = false;
                else
                    fprintf( app.logFile, '%s\n', tmpMesg );
                end
            end
        end
        
        function connect_to_AO(app)
%             app.addToStatus('DEBUG: SKIPPING AO MACHINE FOR TESTING')
%             uialert(app.UIFigure,'Ignoring AO machine for testing.',...
%                 'WARNING',...
%                 'Icon','info');
%             app.AO_connected = true;
%             return;
            
            %% initiate connection
            app.addToStatus('Attempting to connect');
            try
                ret = AO_DefaultStartConnection(app.stimParam.MAC);
            catch e
                app.addToStatus(['Connection failed with error: ' e.message ]);
                return
            end
            if( ret == 10 )
                app.addToStatus('Discovered existing connection.');
                app.addToStatus('Disconnecting.');
                AO_CloseConnection();
                return;
                
            elseif( ret )
                app.addToStatus(['Connection failed with return value ' num2str(ret)]);
                return
                
            else
                app.addToStatus('Connection in progress...');
                
            end
            
            %% ensure connection complete
            for j=1:10
                pause(1);
                app.addToStatus('Checking connection...');
                ret = AO_IsConnected();
                if( ret == 1 )
                    app.addToStatus('Connection completed.');
                    break;
                end
            end
            
            if( ret ~= 1 && ret ~= 10 )
                app.addToStatus('Unable to complete connection.');
                return;
            end
            
            app.AO_connected = true;
        end
        
        
        function stimulate(app)
            if( ~app.keepRecording ) % flag has been set to stop stimulating
                app.addToStatus('Aborting stimulation as recording has ended.');
                return
            end
            ret = AO_IsConnected();
            if( ret ~= 1 )
                app.addToStatus(sprintf('Error: connection status is "%d". Trying to reconnect.', ret ));
                for ii=1:10
                    app.connect_to_AO();
                    pause(2);
                    ret = AO_IsConnected();
                    if( ret == 1)
                        break
                    end
                end
                
                if( ret ~= 1 )
                    app.addToStatus(sprintf('Error: connection status is "%d". Aborting.', ret ));
                    app.StopButtonPushed();
                    return
                end
            end
            
            ret = AO_StartDigitalStimulation(app.stimParam.id_pos, app.stimParam.delay_1, app.stimParam.amp_1, app.stimParam.width_1/1000, app.stimParam.delay_2, app.stimParam.amp_2, app.stimParam.width_2, app.stimParam.stimFreq, app.stimParam.stimDur, app.stimParam.id_neg );
            app.addToStatus(['Stimulation returned: ', num2str(ret)]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            %% path
            if( ~isunix )
                addpath('c:\Program Files (x86)\AlphaOmega\Neuro Omega System SDK\MATLAB_SDK\');
            end
            
            %% SETUP CAMERA
            
            % Obtain list of connected cameras
            if( isunix )
                adaptorName = 'linuxvideo';
                cameraname = '/dev/video0';
                
            else
                adaptorName = 'winvideo';
                cameraname = 'HD USB Camera';
            end
            info = imaqhwinfo(adaptorName);
            listOfCams = {info.DeviceInfo.DeviceName};
            
            % Error check for Camera
            if ~ismember(cameraname,listOfCams)
                ME = MException('MyComponent:noSuchVariable', ...
                    'Variable %s not found',cameraname);
                UIFigureCloseRequest(app)
                throw(ME)
            end
            
            deviceID = find(ismember(listOfCams,cameraname));
            vidFormat = 'YUY2_640x480';
            app.vid = videoinput(adaptorName, deviceID, vidFormat);
            app.vid.LoggingMode = 'disk';
            triggerconfig( app.vid, 'Manual' );
            
            % duration parameters
            app.mainTimerDuration = 0.2;  % seconds
            app.maxRecordingDuration = 24*60*60; % 24 hours converted to seconds
            app.vid.TriggerRepeat = ceil( app.maxRecordingDuration / app.mainTimerDuration );
            app.vid.FrameGrabInterval = 3;
            app.videoDurationPerTrigger = 30*60;  % 30 minutes converted to seconds
            app.vid.FramesPerTrigger = app.videoDurationPerTrigger*10;  % frame rate is 30 fps, but it grabs 3 at a time, so multiply by 10.  Subtract a little to cover the time to retrigger the video.
            app.addToStatus(sprintf('Time between each "check-in": %.2f sec', app.mainTimerDuration ));
            app.addToStatus(sprintf('Video length per segment: %.2f sec', app.videoDurationPerTrigger))
            
            v = getselectedsource(app.vid);
            v.ExposureMode = 'manual';

            % Notify that camera is ready
            addToStatus(app, 'Camera initiated');
            addToStatus(app ,sprintf('Max recording duration: %.1f hours', app.maxRecordingDuration/60/60 ));
            
            %% SETUP ARDUINO
            
            % Error check for ARDUINO
            try
                app.ard = arduino();
            catch
                ME = MException('MyComponent:noSuchVariable', ...
                    'Variable %s not found','ARDUINO');
                UIFigureCloseRequest(app)
                throw(ME)
            end
            
            % Set active items
            app.PreviewButton.Enable = 'off';
            
            % Tell user to set Save directory
            message = sprintf('Choose Save Directory in File Options');
            uialert(app.UIFigure,message,'Choose Save Location',...
                'Icon','info');
            
            %% SET UP STIMULATION
            app.stimParam.doStim = false;
            app.stimParam.id_pos = 10017;
            app.stimParam.id_neg = 10016;
            app.stimParam.amp_1 = 1.0;     % mA
            app.stimParam.amp_2 = 0.0;
            app.stimParam.delay_1 = 0.0;
            app.stimParam.delay_2 = 0.0;
            app.stimParam.width_1 = 90;    % uSec
            app.stimParam.width_2 = 0.0;
            app.stimParam.stimDur = 30.0;  % sec
            app.stimParam.stimFreq = 180;  % Hz
            app.stimParam.MAC = 'a8:1b:6a:27:dc:e9';
            app.AO_connected = false;
            
            %% SET UP LOG
            app.logFile = -1;  % flag that it is not yet ready
            app.firstTimeSavingLog = true;  % flag that it has not saved before
        end

        % Value changed function: PreviewButton
        function PreviewButtonValueChanged(app, event)
            value = app.PreviewButton.Value;
            
            hImage = image(app.Video,zeros(640,480,3));
            app.Video.XLim = [0, 640];
            app.Video.YLim = [0, 480];
            pbaspect(app.Video,[640,480,1])
            if value
                addToStatus(app , 'Preview Started: ')
                preview(app.vid,hImage);
            
            else
                pt = preview(app.vid,hImage);
                pause(0.1)
                grayImage = rgb2gray(pt.CData);
                imshow(grayImage,'Parent',app.Video)
                addToStatus(app , 'Preview Stopped: ')
                
                stoppreview(app.vid);
                
                % DO NOT CHANGE
                addToStatus(app , 'Preview Stopped: ')
                app.PreviewButton.Enable = 'off';
                app.StartButton.Enable = 'on';
                app.StimulationButton.Enable = 'on';
                app.StopButton.Enable = 'off';
            end
            
        end

        % Menu selected function: SaveLocation
        function SaveLocationMenuSelected(app, event)
            app.useDIR = uigetdir();
            
            checkC = 1;
            while checkC
                if app.useDIR ~= 0
                    checkC = 0;
                else
                    app.useDIR = uigetdir();
                end
            end
            
            app.RecLoc.Value = app.useDIR;
            app.RecLoc.FontColor = [0 0 0];
            
            app.SaveLocation.Enable = 'off';
            app.FileName.Enable = 'on';
            
            % Tell user to set Save directory
            message = sprintf('Enter File Name in File Options');
            uialert(app.UIFigure,message,'Enter File Name',...
                'Icon','info');
            
        end

        % Menu selected function: FileName
        function FileNameMenuSelected(app, event)
            
            prompt = {'Enter file name'};
            dlgtitle = {'PatientID', 'Institution','Night#'};
            definput = {'1','UNMC','1'};
            dims = [1 50; 1 50; 1 50];
            outP = inputdlg(dlgtitle,prompt{1},dims,definput);
            if( length(outP) ~= length(definput) )
                app.addToStatus('Ignoring file parameters');
                return;
            end
            
            app.fname = [outP{1},'_',outP{2},'_',outP{3}];
            
            if( isunix )
                fnameVid = [app.fname,'.mj2'];
                app.RecLoc.Value = [app.useDIR , filesep, fnameVid];
                app.vidLog = VideoWriter(app.RecLoc.Value, 'Archival');
            else
                fnameVid = [app.fname,'.mp4'];
                app.RecLoc.Value = [app.useDIR , filesep, fnameVid];
                app.vidLog = VideoWriter(app.RecLoc.Value, 'MPEG-4');
            end
            app.vidLog.FrameRate = 10;
            if( ~isunix )
                app.vidLog.Quality = 55;   % John's code used both 75 and 55 at different places
            end
            app.vid.DiskLogger = app.vidLog;
            
            vidSrc = getselectedsource(app.vid);
            vidLOG = get(app.vid.DiskLogger);
            
            % Update message
            addToStatus(app, ['FrameGrabInterval: ', num2str(get(app.vid,'FrameGrabInterval'))])
            addToStatus(app, ['FramesPerTrigger: ', num2str(get(app.vid,'FramesPerTrigger'))])
            addToStatus(app, ['FrameRateRecord: ', num2str(vidLOG.FrameRate)])
            addToStatus(app, ['FrameRateAcquire: ', get(vidSrc,'FrameRate'), ' '])


            app.FileName.Enable = 'off';
            app.PreviewButton.Enable = 'on';
            app.OPTIONS.Enable = 'off';
            
            %% write out the log
            logFileName = [app.useDIR, filesep, app.fname, '_log.txt'];
            app.logFile = fopen( logFileName, 'w');
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if( app.logFile > 0)
                fclose(app.logFile);
            end
            %fprintf('DEBUG: ignore AO\n')
            AO_CloseConnection();
            delete(app)
        end

        % Menu selected function: CheckToolBoxesCn
        function CheckToolBoxesCnMenuSelected(app, event)
            
            addons = matlab.addons.installedAddons;
            for ci = 1:4
                switch ci
                    case 1
                        ck = license('checkout', 'Image_Acquisition_Toolbox');
                        nameF = 'Image Acquisition Toolbox';
                    case 2
                        ck = license('checkout', 'Video_and_Image_Blockset');
                        nameF = 'Computer Vision Toolbox';
                    case 3
                        ck = any(contains(addons.Identifier,'OSVIDEO'));
                        nameF = 'Image Acquisition Hardware AddOn';
                    case 4
                        ck = any(contains(addons.Identifier,'ML_ARDUINO'));
                        nameF = 'Arduino AddOn';
                end
                
                if ck
                    addToStatus(app , [nameF, '  Installed: '])
                else
                    addToStatus(app , [nameF, '  NOT Installed: '])
                end
                
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% flag that we are going into the run loop
            
            app.keepRecording = true;
            
            %% change the GUI
            
            app.StopButton.Enable = 'on';
            app.StimulationButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.RecLoc.Enable = 'off';
            
            app.addToStatus('Starting recording session');
            imshow(zeros(480,640,'uint8'),'Parent',app.Video)
            text(app.Video, 100, 300,'Recording...',...
                'Color','red',...
                'FontSize',32,'FontWeight','bold')
            
            %% Setup timers
            
            app.mainTimer = timer('TimerFcn',';','StartDelay',app.mainTimerDuration);
            app.addToStatus(['Main trigger repeating every ' num2str(app.mainTimerDuration) ' seconds.']);
            
            % arduino timer allows the pulses to be sent asynchronously
            function timerFunc(t,~)
                writeDigitalPin(t.UserData,'D2',0);
                writeDigitalPin(t.UserData,'D2',1);
                pause(0.005);
                writeDigitalPin(t.UserData,'D2',0);
                pause(0.005);
            end
            ardTimer = timer('TimerFcn', @timerFunc, 'TasksToExecute', 10, 'ExecutionMode', 'fixedRate', 'Period', 0.01 );
            ardTimer.UserData = app.ard;
                
            %% rtestdatestr(now, 'mm.hh.ss.fff')tes,un loop
            triggerCount = 0;
            start(app.vid);
            ticStim = uint64(0);
            ticRun  = tic;
            runLoopDuration = 0;
            loopCount = 0;
            while( app.keepRecording && isrunning( app.vid ) )
                %% START TIMER
                start(app.mainTimer);
                ticLoop = tic;
                loopCount = loopCount + 1;
                                
                %% display
                %app.addToStatus('Run loop.')
                
                %% STIMULATION
                if( app.stimParam.doStim && toc(ticStim) > 300 )
                    ticStim = tic;
                    %app.addToStatus('DEBUG: SKIPPING AO MACHINE FOR TESTING')
                    app.stimulate();
                end
                
                %% FINISH LAST VIDEO TRIGGER
                if( ~islogging(app.vid) ) 
                    %% TTL
                    ardTimer.TasksToExecute = mod(triggerCount-1,6)+1;
                    ardTimer.stop();
                    ardTimer.start();

                    %% START NEXT VIDEO TRIGGER
                    triggerCount = triggerCount + 1;
                    app.addToStatus(sprintf('Sending trigger #%d to video', triggerCount));
                    trigger(app.vid);
                end
                
                runLoopDuration = runLoopDuration + toc(ticLoop);
                
                %% WAIT
                wait(app.mainTimer);
            end
            
            %% Final TTL
            ardTimer.TasksToExecute = 5;
            ardTimer.stop();
            ardTimer.start();
            
            %% finalizing video file
            app.addToStatus('Stopping video acquisition.');
            expectedLength = seconds(toc(ticRun));
            expectedLength.Format = 'hh:mm:ss.SSS';
            if( isrunning(app.vid))
                stop( app.vid );
            end
            app.addToStatus(sprintf('Video total frames acquired: %d. Total frames logged: %d.', app.vid.FramesAcquired, app.vid.DiskLoggerFrameCount ));
            
            %% final logging
            app.addToStatus('Video acquisition complete');
            
            startRecTime = datestr(app.vid.InitialTriggerTime);
            app.addToStatus(sprintf('First Video Trigger was at %s', datestr(startRecTime, 'HH:MM:SS.FFF')));
            app.addToStatus(sprintf('Total number of triggers: %d', triggerCount ));
            app.addToStatus( sprintf('Mean time per run-loop iteration: %.6f', runLoopDuration/loopCount) );
            
            frameRate = app.vid.FramesPerTrigger / app.videoDurationPerTrigger;
            videoLength = seconds(app.vid.FramesAcquired / frameRate);
            videoLength.Format = 'hh:mm:ss.SSS';
            app.addToStatus( sprintf('Video length: %s (actual)', videoLength));
            app.addToStatus( sprintf('Video length: %s (predicted)', expectedLength));
            app.addToStatus( sprintf('Estimated video data coverage: %.2f%%', 100*videoLength/expectedLength));

            %% display
            imshow(zeros(480,640,'uint8'),'Parent',app.Video)
            text(app.Video, 100, 300,...
                'Finished recording.',...
                'Color','red',...
                'FontSize',32,'FontWeight','bold');
            app.StopButton.Enable = 'off';
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable = 'off';
            app.keepRecording = false;
            stop(app.mainTimer);
        end

        % Menu selected function: CLOSEMenu
        function CLOSEMenuSelected(app, event)
            UIFigureCloseRequest(app)
        end

        % Button pushed function: StimulationButton
        function StimulationButtonPushed(app, event)
            %% Get parameters
            
            valid = false;
            while( ~valid )
                
                prompt = 'Set Stimulation Parameters';
                dlgtitle = {'System MAC', 'Contact ID (-)','Contact ID (+)', 'Amp [mA]', 'Width [usec]', 'Freq [Hz]', 'Stimulation on/off?'};
                defaultInput = { app.stimParam.MAC, app.stimParam.id_neg, app.stimParam.id_pos, app.stimParam.amp_1, app.stimParam.width_1, app.stimParam.stimFreq, 'on' };
                for i=2:6
                    defaultInput{i} = num2str(defaultInput{i});
                end
                dims = repmat([1 50], 7, 1 );
                temp = inputdlg(dlgtitle,prompt,dims,defaultInput);
                if( length(temp) ~= length(defaultInput) )
                    app.addToStatus('Stimulation parameters');
                    return;
                end
                
                valid = true;
                app.stimParam.MAC = temp{1};
                app.stimParam.id_neg = str2double(temp{2});
                app.stimParam.id_pos = str2double(temp{3});
                app.stimParam.amp_1 = str2double(temp{4});
                app.stimParam.width_1  = str2double(temp{5});
                app.stimParam.stimFreq  = str2double(temp{6});
                stimOn = temp{7};
                
                % check range
                lowerLimit = [ 10000, 10000, 0.01, 30, 90 ]';
                upperLimit = [ 11000, 11000, 3.0, 500, 300 ]';
                values = cellfun(@str2num, temp(2:end-1) );
                i = find( values < lowerLimit, 1);
                
                if( numel(i) )
                    uialert(app.UIFigure,['Value for "' dlgtitle{i+1} '" is below lower limit of ' num2str(lowerLimit(i)) '. Please correct.'],...
                        'Error',...
                        'Icon','error');
                    valid = false;
                    continue;
                end
                i = find( values > upperLimit, 1);
                if( numel(i) )
                    uialert(app.UIFigure,['Value for "' dlgtitle{i+1} '" is above upper limit of ' num2str(upperLimit(i)) '. Please correct.'],...
                        'Error',...
                        'Icon','error');
                    valid = false;
                    continue;
                end
                i = find(strcmpi( stimOn, {'off', 'on'}));
                if( ~numel(i))
                    uialert(app.UIFigure,['Value for "' dlgtitle{end} '" must be "on" or "off". Please correct.'],...
                        'Error',...
                        'Icon','error');
                    valid = false;
                    continue;
                end
                app.stimParam.doStim = (i == 2);
            end
            
            if( app.stimParam.doStim && ~app.AO_connected )
                app.connect_to_AO();
                if( ~app.AO_connected )
                    uialert(app.UIFigure,'Cannot connect. Turning stimulation off.  Try adjusting cables and click stimulation button again.', ...
                        'ERROR: Cannot connect.',...
                        'Icon','error');
                    app.stimParam.doStim = false;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveLocation = uimenu(app.UIFigure);
            app.SaveLocation.MenuSelectedFcn = createCallbackFcn(app, @SaveLocationMenuSelected, true);
            app.SaveLocation.Text = 'SaveDirectory';
            app.FileName = uimenu(app.UIFigure);
            app.FileName.MenuSelectedFcn = createCallbackFcn(app, @FileNameMenuSelected, true);
            app.FileName.Enable = 'off';
            app.FileName.Text = 'FileName';
            app.OPTIONS = uimenu(app.UIFigure);
            app.OPTIONS.Enable = 'off';
            app.OPTIONS.Text = 'Options';
            app.CheckToolBoxesCn = uimenu(app.OPTIONS);
            app.CheckToolBoxesCn.MenuSelectedFcn = createCallbackFcn(app, @CheckToolBoxesCnMenuSelected, true);
            app.CheckToolBoxesCn.Text = 'CheckToolboxes';
            app.CLOSEMenu = uimenu(app.UIFigure);
            app.CLOSEMenu.MenuSelectedFcn = createCallbackFcn(app, @CLOSEMenuSelected, true);
            app.CLOSEMenu.Text = 'CLOSE';
            app.PreviewButton = uibutton(app.UIFigure, 'state');
            app.PreviewButton.ValueChangedFcn = createCallbackFcn(app, @PreviewButtonValueChanged, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Tooltip = {'Preview video'};
            app.PreviewButton.Text = 'Preview';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.Status = uitextarea(app.UIFigure);
            app.RecordSaveLocationLabel = uilabel(app.UIFigure);
            app.RecordSaveLocationLabel.Text = 'Record Save Location';
            app.RecLoc = uieditfield(app.UIFigure, 'text');
            app.RecLoc.Editable = 'off';
            app.RecLoc.Value = 'File Save location';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Tooltip = {'Start Video and (optionally) stimulation'};
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Tooltip = {'Stop video and (f applicable) stimulation'};
            app.StopButton.Text = 'Stop';
            app.StimulationButton = uibutton(app.UIFigure, 'push');
            app.StimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StimulationButtonPushed, true);
            app.StimulationButton.Tooltip = {'Adjust stimulation parameters and turn stimulation on.'};
            app.StimulationButton.Text = 'Stimulation';
            app.Video = uiaxes(app.UIFigure);
            app.Video.XTick = [];
            app.Video.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Record_IR_Video_and_Stim_v03042021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end